classdef SPTbot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        uTracksavepathEditField         matlab.ui.control.EditField
        uTracksavepathEditFieldLabel    matlab.ui.control.Label
        uTrackpathEditField             matlab.ui.control.EditField
        uTrackpathEditFieldLabel        matlab.ui.control.Label
        RunStep1orstandaloneFijimacrobeforeStep2Label  matlab.ui.control.Label
        orginfo                         matlab.ui.control.Button
        fijiinfo                        matlab.ui.control.Button
        FormattoACDCButton              matlab.ui.control.Button
        LoadpathsButton                 matlab.ui.control.Button
        SavepathsButton                 matlab.ui.control.Button
        ijpathoptionalEditField         matlab.ui.control.EditField
        ijpathoptionalEditFieldLabel    matlab.ui.control.Label
        mijpathEditField                matlab.ui.control.EditField
        mijpathEditFieldLabel           matlab.ui.control.Label
        FijipathEditField               matlab.ui.control.EditField
        FijipathEditFieldLabel          matlab.ui.control.Label
        Step2OrganizeFilesLabel         matlab.ui.control.Label
        RedoButton                      matlab.ui.control.Button
        RunFijiProcessingButton         matlab.ui.control.Button
        Step1PrepareImagesLabel         matlab.ui.control.Label
        Step0SetUpLabel                 matlab.ui.control.Label
        uTrackparametersTab             matlab.ui.container.Tab
        uTrackJaqamanetal2008NatureMethodsLabel  matlab.ui.control.Label
        GapPenaltyInfo                  matlab.ui.control.Button
        GappenaltyEditField             matlab.ui.control.EditField
        GappenaltyEditFieldLabel        matlab.ui.control.Label
        DontchangeunlessyouknowwhatyouaredoingLabel  matlab.ui.control.Label
        OptionalparametersLabel         matlab.ui.control.Label
        ImportantparametersLabel        matlab.ui.control.Label
        BasicparametersLabel            matlab.ui.control.Label
        LocalDensityInfo                matlab.ui.control.Button
        BrownInfo                       matlab.ui.control.Button
        LinearMotionInfo                matlab.ui.control.Button
        MergeSplitInfo                  matlab.ui.control.Button
        MaxAngleInfo                    matlab.ui.control.Button
        MaxRadiusInfo                   matlab.ui.control.Button
        MinRadiusInfo                   matlab.ui.control.Button
        MinLengthInfo                   matlab.ui.control.Button
        GapInfo                         matlab.ui.control.Button
        PixelInfo                       matlab.ui.control.Button
        ResettodefaultsButton           matlab.ui.control.Button
        LoadparametersButton            matlab.ui.control.Button
        SaveparametersButton            matlab.ui.control.Button
        MaxsearchangleEditField         matlab.ui.control.EditField
        MaxsearchangleEditFieldLabel    matlab.ui.control.Label
        MaxsearchradiusnmEditField      matlab.ui.control.EditField
        MaxsearchradiusnmEditFieldLabel  matlab.ui.control.Label
        UselocaldensityEditField        matlab.ui.control.EditField
        UselocaldensityEditFieldLabel   matlab.ui.control.Label
        BrownstdmultiplerEditField      matlab.ui.control.EditField
        BrownstdmultiplerEditFieldLabel  matlab.ui.control.Label
        LinearmotionEditField           matlab.ui.control.EditField
        LinearmotionEditFieldLabel      matlab.ui.control.Label
        MinsearchradiusnmEditField      matlab.ui.control.EditField
        MinsearchradiusnmEditFieldLabel  matlab.ui.control.Label
        MinlengthframesEditField        matlab.ui.control.EditField
        MinlengthframesEditFieldLabel   matlab.ui.control.Label
        MergesplitEditField             matlab.ui.control.EditField
        MergesplitEditFieldLabel        matlab.ui.control.Label
        MaxgapwindowframesEditField     matlab.ui.control.EditField
        MaxgapwindowframesEditFieldLabel  matlab.ui.control.Label
        PixelsizenmEditField            matlab.ui.control.EditField
        PixelsizenmEditFieldLabel       matlab.ui.control.Label
        NOBIASparametersTab             matlab.ui.container.Tab
        NOBIASChenetal2021FrontiersinBioinformaticsLabel  matlab.ui.control.Label
        Button_24                       matlab.ui.control.Button
        Button_23                       matlab.ui.control.Button
        Button_22                       matlab.ui.control.Button
        Button_21                       matlab.ui.control.Button
        Button_20                       matlab.ui.control.Button
        Button_19                       matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        AsymmetricEditField             matlab.ui.control.NumericEditField
        AsymmetricEditFieldLabel        matlab.ui.control.Label
        PlotEditField                   matlab.ui.control.NumericEditField
        PlotEditFieldLabel              matlab.ui.control.Label
        DisplayfrequencyEditField       matlab.ui.control.NumericEditField
        DisplayfrequencyEditFieldLabel  matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        DisplayparametersLabel          matlab.ui.control.Label
        BurnEditField                   matlab.ui.control.NumericEditField
        BurnEditFieldLabel              matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        DontchangeunlessyouknowwhatyouaredoingLabel_2  matlab.ui.control.Label
        AdvancedparametersLabel         matlab.ui.control.Label
        BasicparametersLabel_2          matlab.ui.control.Label
        ResettodefaultsButton_2         matlab.ui.control.Button
        Saveparameters_NOBIAS           matlab.ui.control.Button
        LoadparametersButton_NOBIAS     matlab.ui.control.Button
        Button_15                       matlab.ui.control.Button
        Button_14                       matlab.ui.control.Button
        Button_13                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        PixelsizeumEditField            matlab.ui.control.NumericEditField
        PixelsizeumEditFieldLabel       matlab.ui.control.Label
        FrametimesecEditField           matlab.ui.control.NumericEditField
        FrametimesecEditFieldLabel      matlab.ui.control.Label
        MotionblurEditField             matlab.ui.control.NumericEditField
        MotionblurEditFieldLabel        matlab.ui.control.Label
        KappaEditField                  matlab.ui.control.NumericEditField
        KappaEditFieldLabel             matlab.ui.control.Label
        TrackingfilteringTab            matlab.ui.container.Tab
        BitspxoptionalEditField         matlab.ui.control.NumericEditField
        BitspxoptionalEditFieldLabel    matlab.ui.control.Label
        RunCellACDCseeguideformoreinfoLabel  matlab.ui.control.Label
        WigglepxoptionalEditField       matlab.ui.control.NumericEditField
        WigglepxoptionalEditFieldLabel  matlab.ui.control.Label
        nmpxEditField                   matlab.ui.control.NumericEditField
        nmpxEditFieldLabel              matlab.ui.control.Label
        MaxblinkEditField               matlab.ui.control.NumericEditField
        MaxblinkEditFieldLabel          matlab.ui.control.Label
        MinlocsEditField                matlab.ui.control.NumericEditField
        MinlocsEditFieldLabel           matlab.ui.control.Label
        Step8NOBIASAnalysisLabel_3      matlab.ui.control.Label
        Button_17                       matlab.ui.control.Button
        RunNOBIASButton_3               matlab.ui.control.Button
        Button_16                       matlab.ui.control.Button
        RunMSDAnalysisButton_3          matlab.ui.control.Button
        Step7MSDAnalysisLabel_3         matlab.ui.control.Label
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        OrganizemasksButton             matlab.ui.control.Button
        Step4OrganizeMasksLabel         matlab.ui.control.Label
        FiltertracksButton              matlab.ui.control.Button
        Step6FilterSCTracksLabel        matlab.ui.control.Label
        RunuTrackButton                 matlab.ui.control.Button
        Step5RunTrackingLabel           matlab.ui.control.Label
        Step3CreateMasksLabel           matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        Property % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FijipathEditField
        function FijiPath(app, event)
            fijipath = app.FijipathEditField.Value;  
        end

        % Value changed function: mijpathEditField
        function MijPath(app, event)
            mijpath = app.mijpathEditField.Value;  
        end

        % Value changed function: ijpathoptionalEditField
        function Ijpath(app, event)
            ijpath = app.ijpathoptionalEditField.Value;
        end

        % Value changed function: uTrackpathEditField
        function uTrackPath(app, event)
            uTrackpath = app.uTrackpathEditField.Value;
        end

        % Value changed function: uTracksavepathEditField
        function uTrackSavePath(app, event)
            uTrackSavepath = app.uTracksavepathEditField.Value;        
        end

        % Button pushed function: RunFijiProcessingButton
        function RunFijiProcessingButtonPushed(app, event)
            fijipath = app.FijipathEditField.Value;
            mijpath = app.mijpathEditField.Value;
            ijpath = app.ijpathoptionalEditField.Value;
            Fiji_processing(fijipath,mijpath,ijpath)
            app.RunFijiProcessingButton.Enable = 'off';
        end

        % Button pushed function: RedoButton
        function RedoFiji(app, event)
            if app.RunFijiProcessingButton.Enable == 'off'
                app.RunFijiProcessingButton.Enable = 'on';
            end 
        end

        % Button pushed function: FormattoACDCButton
        function formatACDC(app, event)
            fileMgmt
        end

        % Button pushed function: OrganizemasksButton
        function MaskOrg(app, event)
            maskMgmt
        end

        % Button pushed function: LoadparametersButton
        function loaduTrackparams(app, event)
            uiopen("matlab")
            % 'pixelSize_','gapWindow','MinLength','MinRadius','MaxRadius','MaxAngle','MergeSplit','LinearMotion','BrownMult','LocalDensity', 'GapPenalty'
            app.PixelsizenmEditField.Value = string(pixelSize_);
            app.MaxgapwindowframesEditField.Value = string(gapWindow);
            app.MinlengthframesEditField.Value = string(MinLength);
            app.MinsearchradiusnmEditField.Value = string(MinRadius);
            app.MaxsearchradiusnmEditField.Value = string(MaxRadius);
            app.MaxsearchangleEditField.Value = string(MaxAngle);
            app.MergesplitEditField.Value = string(MergeSplit);
            app.LinearmotionEditField.Value = string(LinearMotion);
            app.BrownstdmultiplerEditField.Value = string(BrownMult);
            app.UselocaldensityEditField.Value = string(LocalDensity);
            app.GappenaltyEditField.Value = string(GapPenalty);
        end

        % Value changed function: PixelsizenmEditField
        function pxSize(app, event)
            pixelSize_ = app.PixelsizenmEditField.Value;        
        end

        % Value changed function: MaxgapwindowframesEditField
        function gapWindow(app, event)
            gapWindow = app.MaxgapwindowframesEditField.Value;            
        end

        % Value changed function: MinlengthframesEditField
        function MinLength(app, event)
            MinLength = app.MinlengthframesEditField.Value;          
        end

        % Value changed function: MinsearchradiusnmEditField
        function MinRadius(app, event)
            MinRadius = app.MinsearchradiusnmEditField.Value;            
        end

        % Value changed function: MaxsearchradiusnmEditField
        function MaxRadius(app, event)
            MaxRadius = app.MaxsearchradiusnmEditField.Value;            
        end

        % Value changed function: MaxsearchangleEditField
        function MaxAngle(app, event)
            MaxAngle = app.MaxsearchangleEditField.Value;       
        end

        % Value changed function: MergesplitEditField
        function MergeSplit(app, event)
            MergeSplit = app.MergesplitEditField.Value;            
        end

        % Value changed function: LinearmotionEditField
        function LinearMotion(app, event)
            LinearMotion = app.LinearmotionEditField.Value;           
        end

        % Value changed function: BrownstdmultiplerEditField
        function BrownMult(app, event)
            BrownMult = app.BrownstdmultiplerEditField.Value;          
        end

        % Value changed function: UselocaldensityEditField
        function LocalDensity(app, event)
            LocalDensity = app.UselocaldensityEditField.Value;
        end

        % Value changed function: GappenaltyEditField
        function GapPenalty(app, event)
            GapPenalty = app.GappenaltyEditField.Value;  
        end

        % Button pushed function: RunuTrackButton
        function uTrack(app, event)
            % Grab parameters 
            uTrackpath = app.uTrackpathEditField.Value;
            uTrackSavepath = app.uTracksavepathEditField.Value;
            pixelSize_ = str2double(app.PixelsizenmEditField.Value);  
            gapWindow = str2double(app.MaxgapwindowframesEditField.Value); 
            MinLength = str2double(app.MinlengthframesEditField.Value);   
            MinRadius = str2double(app.MinsearchradiusnmEditField.Value);
            MaxRadius = str2double(app.MaxsearchradiusnmEditField.Value); 
            MaxAngle = str2double(app.MaxsearchangleEditField.Value);
            MergeSplit = str2double(app.MergesplitEditField.Value); 
            LinearMotion = str2double(app.LinearmotionEditField.Value);
            BrownMult = str2double(app.BrownstdmultiplerEditField.Value);
            LocalDensity = str2double(app.UselocaldensityEditField.Value);
            GapPenalty = str2double(app.GappenaltyEditField.Value);
            Tracking(uTrackpath,uTrackSavepath,pixelSize_,gapWindow,MinLength,MinRadius,MaxRadius,MaxAngle,MergeSplit,...
                LinearMotion,BrownMult,LocalDensity,GapPenalty)
            % Disable main UI while open
            app.RunuTrackButton.Enable = 'off';
            % open dialogue box
            % uTrack_settings
        end

        % Button pushed function: SaveparametersButton
        function SaveuTrackparams(app, event)
            pixelSize_ = app.PixelsizenmEditField.Value;  
            gapWindow = app.MaxgapwindowframesEditField.Value; 
            MinLength = app.MinlengthframesEditField.Value;   
            MinRadius = app.MinsearchradiusnmEditField.Value;
            MaxRadius = app.MaxsearchradiusnmEditField.Value; 
            MaxAngle = app.MaxsearchangleEditField.Value;
            MergeSplit = app.MergesplitEditField.Value; 
            LinearMotion = app.LinearmotionEditField.Value;
            BrownMult = app.BrownstdmultiplerEditField.Value;
            LocalDensity = app.UselocaldensityEditField.Value;
            GapPenalty = app.GappenaltyEditField.Value;
            % conversion to double
            pixelSize_ = str2double(pixelSize_); gapWindow = str2double(gapWindow); MinLength = str2double(MinLength);
            MinRadius = str2double(MinRadius); MaxRadius = str2double(MaxRadius); MaxAngle = str2double(MaxAngle);
            MergeSplit = str2double(MergeSplit); LinearMotion = str2double(LinearMotion); BrownMult = str2double(BrownMult);
            LocalDensity = str2double(LocalDensity); GapPenalty = str2double(GapPenalty);
            uisave({'pixelSize_','gapWindow','MinLength','MinRadius','MaxRadius','MaxAngle','MergeSplit','LinearMotion','BrownMult','LocalDensity', 'GapPenalty'})
        end

        % Button pushed function: ResettodefaultsButton
        function DefaultReset(app, event)
            app.PixelsizenmEditField.Value = string(99);
            app.MaxgapwindowframesEditField.Value = string(6);
            app.MinlengthframesEditField.Value = string(1);
            app.MinsearchradiusnmEditField.Value = string(10);
            app.MaxsearchradiusnmEditField.Value = string(400);
            app.MaxsearchangleEditField.Value = string(360);
            app.MergesplitEditField.Value = string(0);
            app.LinearmotionEditField.Value = string(1);
            app.BrownstdmultiplerEditField.Value = string(3);
            app.UselocaldensityEditField.Value = string(1);
            app.GappenaltyEditField.Value = string(1);
        end

        % Button pushed function: LoadpathsButton
        function LoadPaths(app, event)
            uiopen("matlab")
            % app.PixelsizenmEditField.Value = string(pixelSize_);
            app.FijipathEditField.Value = fijipath;
            app.mijpathEditField.Value = mijpath;
            app.ijpathoptionalEditField.Value = ijpath;
            app.uTrackpathEditField.Value = uTrackpath;
            app.uTracksavepathEditField.Value = uTrackSavepath;

        end

        % Button pushed function: SavepathsButton
        function SaveAnalysisPaths(app, event)
            fijipath = app.FijipathEditField.Value;
            mijpath = app.mijpathEditField.Value;
            ijpath = app.ijpathoptionalEditField.Value;
            uTrackpath = app.uTrackpathEditField.Value;
            uTrackSavepath = app.uTracksavepathEditField.Value; 
            uisave({'fijipath','mijpath','ijpath','uTrackpath','uTrackSavepath'})

        end

        % Callback function: not associated with a component
        function cycles(app, event)
            Cycles = app.CyclesEditField.Value;           
        end

        % Value changed function: MinlocsEditField
        function minLocs(app, event)
            MinLocs = app.MinlocsEditField.Value;            
        end

        % Value changed function: MaxblinkEditField
        function maxBlink(app, event)
            MaxBlink = app.MaxblinkEditField.Value;      
        end

        % Value changed function: nmpxEditField
        function nm2px(app, event)
            Nm2Px = app.nmpxEditField.Value;    
        end

        % Value changed function: WigglepxoptionalEditField
        function wiggle(app, event)
            w = app.WigglepxoptionalEditField.Value;
        end

        % Value changed function: BitspxoptionalEditField
        function small(app, event)
            throwOut = app.BitspxoptionalEditField.Value;   
        end

        % Button pushed function: FiltertracksButton
        function FilterTracks(app, event)
            Cycles = app.CyclesEditField.Value;
            MinLocs = app.MinlocsEditField.Value;
            MaxBlink = app.MaxblinkEditField.Value;
            Nm2Px = app.nmpxEditField.Value;
            w = app.WigglepxoptionalEditField.Value;
            throwOut = app.BitspxoptionalEditField.Value; 
            Cycles = str2double(Cycles); MinLocs = str2double(MinLocs); MaxBlink = str2double(MaxBlink);
            Nm2Px = str2double(Nm2Px); w = str2double(w); throwOut = str2double(throwOut);
            REAL_trackFiltering(Cycles,Nm2Px,MinLocs,MaxBlink,w,throwOut)
        end

        % Value changed function: FrametimesecEditField
        function Nframetime(app, event)
            NFrameTime = app.FrametimesecEditField.Value;   
        end

        % Value changed function: PixelsizeumEditField
        function NpxSize(app, event)
            Npsize = app.PixelsizeumEditField.Value;            
        end

        % Value changed function: MotionblurEditField
        function NmotionBlur(app, event)
            NBlur = app.MotionblurEditField.Value; 
        end

        % Value changed function: KappaEditField
        function Nkappa(app, event)
            NKappa = app.KappaEditField.Value;          
        end

        % Value changed function: AsymmetricEditField
        function Nasym(app, event)
            NAsym = app.AsymmetricEditField.Value;        
        end

        % Value changed function: IterationsEditField
        function Niter(app, event)
            NIter = app.IterationsEditField.Value; 
        end

        % Value changed function: DisplayfrequencyEditField
        function Nfreq(app, event)
            NFreq = app.DisplayfrequencyEditField.Value;
        end

        % Value changed function: PlotEditField
        function Nplot(app, event)
            NPlot = app.PlotEditField.Value;           
        end

        % Value changed function: GammaEditField
        function Ngamma(app, event)
            NGamma = app.GammaEditField.Value;       
        end

        % Value changed function: AlphaEditField
        function Nalpha(app, event)
            NAlpha = app.AlphaEditField.Value;
        end

        % Value changed function: BurnEditField
        function Nburn(app, event)
            NBurn = app.BurnEditField.Value;
        end

        % Button pushed function: LoadparametersButton_NOBIAS
        function Nloadparams(app, event)
            uiopen("matlab")
            app.FrametimesecEditField.Value = string(NFrameTime);
            app.PixelsizeumEditField.Value = string(Npsize);
            app.MotionblurEditField.Value = string(NBlur);
            app.KappaEditField.Value = string(NKappa);
            app.AsymmetricEditField.Value = string(NAsym);
            app.IterationsEditField.Value = string(NIter);
            app.DisplayfrequencyEditField.Value = string(NFreq);
            app.PlotEditField.Value = string(NPlot);
            app.GammaEditField.Value = string(NGamma);
            app.AlphaEditField.Value = string(NAlpha);
            app.BurnEditField.Value = string(NBurn);
        end

        % Button pushed function: Saveparameters_NOBIAS
        function Nsaveparams(app, event)
               NFrameTime = app.FrametimesecEditField.Value;
               Npsize = app.PixelsizeumEditField.Value;
               NBlur = app.MotionblurEditField.Value;
               NKappa = app.KappaEditField.Value;
               NAsym = app.AsymmetricEditField.Value;
               NIter = app.IterationsEditField.Value;
               NFreq = app.DisplayfrequencyEditField.Value;
               NPlot = app.PlotEditField.Value;
               NGamma = app.GammaEditField.Value;
               NAlpha = app.AlphaEditField.Value;
               NBurn = app.BurnEditField.Value;
               NFrameTime = str2double(NFrameTime); Npsize = str2double(Npsize); NBlur = str2double(NBlur); 
               NKappa = str2double(NKappa); NAsym = str2double(NAsym); NIter = str2double(NIter); NFreq = str2double(NFreq);
               NPlot = str2double(NPlot); NGamma = str2double(NGamma); NAlpha = str2double(NAlpha); NBurn = str2double(NBurn);
              uisave({'NFrameTime','Npsize','NBlur','NKappa','NAsym', 'NIter','NFreq','NPlot','NGamma','NAlpha','NBurn'})
        end

        % Button pushed function: ResettodefaultsButton_2
        function Ndefaults(app, event)
            app.FrametimesecEditField.Value = string(0.1);  
            app.PixelsizeumEditField.Value = string(0.099); 
            app.MotionblurEditField.Value = string(1); 
            app.KappaEditField.Value = string(10);  
            app.AsymmetricEditField.Value = string(0); 
            app.IterationsEditField.Value = string(20000); 
            app.DisplayfrequencyEditField.Value = string(1000);
            app.PlotEditField.Value = string(0);  
            app.GammaEditField.Value = string(0.1); 
            app.AlphaEditField.Value = string(1);
            app.BurnEditField.Value = string(200);
        end

        % Button pushed function: RunNOBIASButton_3
        function RunNOBIAS(app, event)
            NOBIAS_converter
            % add in params here
            out = NOBIAS(data);
        end

        % Button pushed function: fijiinfo
        function FijiInfo(app, event)
            msgbox(["Running Fiji processing will open up Fiji through Matlab."; "A Fiji macro " + ...
                "will automatically run that adds two reference planes together, flips them vertically," + ...
                "optionally adjusts brightness and constrast, and saves the result."; "Alternatively, " + ...
                "you can run this macro with Fiji outside of Matlab."],"Fiji processing")
        end

        % Button pushed function: orginfo
        function OrgInfo(app, event)
            msgbox(["After creating files called 'Add_planes.tif', this will automatically organize and create reference images in the format Cell-ACDC expects:"; ...
                "Position_n > Images > Add_planes and Add_planes_ref"; ...
                "!!! Ensure file format before starting is 'Microscopy Day > S1G1R1, S1G1R1,...' !!!"],"ACDC formatting")
        end

        % Button pushed function: Button_4
        function MaskOrgInfo(app, event)
            msgbox(["After running Cell-ACDC, organizing masks will do two things:"; ...
                "1) Convert reference .npz masks to .mat files"; ...
                "2) Move masks into their respective gonad folders"],"Mask organization")
        end

        % Button pushed function: Button_7
        function uTrackInfo(app, event)
            msgbox(["Track raw Vutara output by cycle. See parameters tab for more info."; ...
                "Adapted from Jaqaman et. al, 2008"],"uTrack info")
        end

        % Button pushed function: PixelInfo
        function PxInfo(app, event)
            msgbox(["Size of xy pixels in nm"; ...
                "Default is 99"],"Pixel info");
        end

        % Button pushed function: GapInfo
        function Gapinfo(app, event)
            msgbox(["Maximum allowed time gap (in frames) between a track segment end and a track segment start that allows linking them"; ...
                "Default is 6"],"Gap window info")
        end

        % Button pushed function: MinLengthInfo
        function minLengthInfo(app, event)
            msgbox(["Minimum length of track segments from linking to be used in gap closing"; ...
                "Default is 1"],"Min length info")
        end

        % Button pushed function: MinRadiusInfo
        function minRadiusInfo(app, event)
            msgbox(["Minimum allowed search radius (in nm)."; ...
                "The search radius is calculated on the spot in the code given a feature's motion parameters.";...
                "If it happens to be smaller than this minimum, it will be increased to the minimum."; ...
                "Default is 10"],"Minimum search radius info")
        end

        % Button pushed function: MaxRadiusInfo
        function maxRadiusInfo(app, event)
            msgbox(["Maximum allowed search radius (in nm).";
                "If a feature's calculated search radius is larger than this maximum, it will be reduced to this maximum."; ...
                "Default is 400"],"Maximum search radius info")
        end

        % Button pushed function: MaxAngleInfo
        function maxAngleInfo(app, event)
            msgbox(["Maximum angle between the directions of motion of two tracks that allows linking them (and thus closing a gap), in degrees"; ...
                "Think of it as the equivalent of a search radius but for angles."; ...
                "Default is 360"],"Max angle info")
        end

        % Button pushed function: MergeSplitInfo
        function mergeSplitInfo(app, event)
            msgbox(["Merging and splitting of tracks, requires amplitude measurement";
                "1 if merging and splitting are to be considered";...
                "2 if only merging is to be considered";...
                "3 if only splitting is to be considered"; ...
                "0 if no merging or splitting are to be considered."; ...
                "Default is 0"],"Merge/split info")
        end

        % Button pushed function: LinearMotionInfo
        function linearMotionInfo(app, event)
            msgbox(["1 to use linear motion Kalman filter"; ...
                "0 to not use filter"; ...
                "Default is 1"],"Linear motion info")
        end

        % Button pushed function: BrownInfo
        function brownInfo(app, event)
            msgbox(["Multiplication factor to calculate search radius from standard deviation"; ...
                "Default is 3"],"Brown std mult info")
        end

        % Button pushed function: LocalDensityInfo
        function localDensityInfo(app, event)
            msgbox(["1 if you want to expand the search radius of isolated features in the linking (initial tracking) step"; ...
                "0 if not"; ...
                "Default is 1"],"Local density info")
        end

        % Button pushed function: GapPenaltyInfo
        function gapPenaltyInfo(app, event)
            msgbox(["Penalty for increasing temporary disappearance time (disappearing for n frames gets a penalty of gapPenalty^n)"; ...
                "Default is 1 (no penalty)"],"Gap penalty info")
        end

        % Button pushed function: Button_12
        function NframeInfo(app, event)
            msgbox(["Frame time in seconds, i.e. 100 ms = 0.1 s. Also known as exposure time"; ...
                "Default is 0.1"],"NOBIAS frame info")
        end

        % Button pushed function: Button_13
        function NpxSizeInfo(app, event)
            msgbox(["Pixel size for our microscope, in um, i.e. 99 nm/px = 0.099"; ...
                "Default is 0.099"],"NOBIAS pixel info")
        end

        % Button pushed function: Button_14
        function NmotionBlurInfo(app, event)
            msgbox(["Whether (1) or not (0) to do a motion blur correction. This correction correlates steps in each track, which helps take into account 2D localization error";...
                "Default is 1"],"NOBIAS motion blur info")
        end

        % Button pushed function: Button_15
        function NkappaInfo(app, event)
            msgbox(["The 'stickiness' parameter, typically ranging from 1-100. Kappa should be increased for 'worse' data (i.e., lots of motion blur) or";...
                "if your final results have multiple states with super similar diffusion coefficients";...
                "Default is 1"],"NOBIAS kappa info")
        end

        % Button pushed function: Button_18
        function NasymInfo(app, event)
            msgbox(["Set to 1 if you want diffusion coefficients in both x and y, 0 for an overall 2D diffusion coefficient";...
                "Default is 0"],"NOBIAS asymmetry info")
        end

        % Button pushed function: Button_19
        function NiterInfo(app, event)
            msgbox(["How many times to run the MCMC sampling - this number should be in the thousands";...
                "Default is 20000"],"NOBIAS iteration info")
        end

        % Button pushed function: Button_20
        function NfreqInfo(app, event)
            msgbox(["Display frequency of current number of states (so you can keep track of NOBIAS's progress).";...
                "This number should evenly divide the number of iterations";...
                "Default is 1000"],"NOBIAS frequency info")
        end

        % Button pushed function: Button_21
        function NplotInfo(app, event)
            msgbox(["If you would like (1) or not like (0) to display the built-in plot showing diffusion coefficients";...
                "The plot display is hardcoded into NOBIAS, so often the axes of the plots are pretty useless";...
                "Default is 0"],"NOBIAS plot info")
        end

        % Button pushed function: Button_22
        function NgammaInfo(app, event)
            msgbox(["The concentration parameter of the Dirichlet process (the prior)";...
                "A smaller gamma make the DP more like the uniform distribution while a bigger gamma is more sparsely distributed";...
                "Default is 0.1"],"NOBIAS gamma info")
        end

        % Button pushed function: Button_23
        function NalphaInfo(app, event)
            msgbox(["A parameter that impact how the MCMC is sampled using the Blocked Sampling method, see Fox et al., 2008";...
                "Default is 1"],"NOBIAS alpha info")
        end

        % Button pushed function: Button_24
        function NburnInfo(app, event)
            msgbox(["Number of samples that are thrown out at the beginning of MCMC sampling (i.e., the 'burn-in' period)";...
                "Default is 200"],"NOBIAS burn info")
        end

        % Button pushed function: Button_17
        function FIlterTracksInfo(app, event)
            msgbox(["This filtering takes uTrack generated tracks and reference masks to spit out tracks that are in the SC";...
                "You can also filter with basic criteria, like minimum number of localizations and time spent blinking";...
                "Min locs: Minimum number of localization in a track";...
                "Max blink: Maximum proportion of a track that it can spend blinking (i.e., 50% = 0.5)";...
                "nm2px: Conversion of nm to pixels, 99 nm/px for our scope";...
                "Bits: Size (px) of masks to exclude (helpful if lots of tiny, noisy pieces)";...
                "Wiggle: Area (px) around a white pixel in a mask to also consider for if a track is 'in' the SC"],"Filtering tracks info")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.Step0SetUpLabel = uilabel(app.SetupTab);
            app.Step0SetUpLabel.Text = 'Step 0: Set Up';
            app.Step1PrepareImagesLabel = uilabel(app.SetupTab);
            app.Step1PrepareImagesLabel.Text = 'Step 1: Prepare Images';
            app.RunFijiProcessingButton = uibutton(app.SetupTab, 'push');
            app.RunFijiProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @RunFijiProcessingButtonPushed, true);
            app.RunFijiProcessingButton.Text = 'Run Fiji Processing';
            app.RedoButton = uibutton(app.SetupTab, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoFiji, true);
            app.RedoButton.Text = 'Redo';
            app.Step2OrganizeFilesLabel = uilabel(app.SetupTab);
            app.Step2OrganizeFilesLabel.Text = 'Step 2: Organize Files';
            app.FijipathEditFieldLabel = uilabel(app.SetupTab);
            app.FijipathEditFieldLabel.Text = 'Fiji path';
            app.FijipathEditField = uieditfield(app.SetupTab, 'text');
            app.FijipathEditField.ValueChangedFcn = createCallbackFcn(app, @FijiPath, true);
            app.mijpathEditFieldLabel = uilabel(app.SetupTab);
            app.mijpathEditFieldLabel.Text = 'mij path';
            app.mijpathEditField = uieditfield(app.SetupTab, 'text');
            app.mijpathEditField.ValueChangedFcn = createCallbackFcn(app, @MijPath, true);
            app.ijpathoptionalEditFieldLabel = uilabel(app.SetupTab);
            app.ijpathoptionalEditFieldLabel.Text = 'ij path (optional)';
            app.ijpathoptionalEditField = uieditfield(app.SetupTab, 'text');
            app.ijpathoptionalEditField.ValueChangedFcn = createCallbackFcn(app, @Ijpath, true);
            app.SavepathsButton = uibutton(app.SetupTab, 'push');
            app.SavepathsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisPaths, true);
            app.SavepathsButton.Text = 'Save paths';
            app.LoadpathsButton = uibutton(app.SetupTab, 'push');
            app.LoadpathsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPaths, true);
            app.LoadpathsButton.Text = 'Load paths';
            app.FormattoACDCButton = uibutton(app.SetupTab, 'push');
            app.FormattoACDCButton.ButtonPushedFcn = createCallbackFcn(app, @formatACDC, true);
            app.FormattoACDCButton.Text = 'Format to ACDC';
            app.fijiinfo = uibutton(app.SetupTab, 'push');
            app.fijiinfo.ButtonPushedFcn = createCallbackFcn(app, @FijiInfo, true);
            app.fijiinfo.Text = '?';
            app.orginfo = uibutton(app.SetupTab, 'push');
            app.orginfo.ButtonPushedFcn = createCallbackFcn(app, @OrgInfo, true);
            app.orginfo.Text = '?';
            app.RunStep1orstandaloneFijimacrobeforeStep2Label = uilabel(app.SetupTab);
            app.RunStep1orstandaloneFijimacrobeforeStep2Label.Text = 'Run Step 1 or standalone Fiji macro before Step 2!';
            app.uTrackpathEditFieldLabel = uilabel(app.SetupTab);
            app.uTrackpathEditFieldLabel.Text = 'uTrack path';
            app.uTrackpathEditField = uieditfield(app.SetupTab, 'text');
            app.uTrackpathEditField.ValueChangedFcn = createCallbackFcn(app, @uTrackPath, true);
            app.uTracksavepathEditFieldLabel = uilabel(app.SetupTab);
            app.uTracksavepathEditFieldLabel.Text = 'uTrack save path';
            app.uTracksavepathEditField = uieditfield(app.SetupTab, 'text');
            app.uTracksavepathEditField.ValueChangedFcn = createCallbackFcn(app, @uTrackSavePath, true);
            app.uTrackparametersTab = uitab(app.TabGroup);
            app.uTrackparametersTab.Title = 'uTrack parameters';
            app.PixelsizenmEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelsizenmEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @pxSize, true);
            app.PixelsizenmEditField.Value = '99';
            app.MaxgapwindowframesEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MaxgapwindowframesEditFieldLabel.Text = 'Max gap window (frames)';
            app.MaxgapwindowframesEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MaxgapwindowframesEditField.ValueChangedFcn = createCallbackFcn(app, @gapWindow, true);
            app.MaxgapwindowframesEditField.Value = '6';
            app.MergesplitEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MergesplitEditFieldLabel.Text = 'Merge/split?';
            app.MergesplitEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MergesplitEditField.ValueChangedFcn = createCallbackFcn(app, @MergeSplit, true);
            app.MergesplitEditField.Value = '0';
            app.MinlengthframesEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MinlengthframesEditFieldLabel.Text = 'Min length (frames)';
            app.MinlengthframesEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MinlengthframesEditField.ValueChangedFcn = createCallbackFcn(app, @MinLength, true);
            app.MinlengthframesEditField.Value = '1';
            app.MinsearchradiusnmEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MinsearchradiusnmEditFieldLabel.Text = 'Min search radius (nm)';
            app.MinsearchradiusnmEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MinsearchradiusnmEditField.ValueChangedFcn = createCallbackFcn(app, @MinRadius, true);
            app.MinsearchradiusnmEditField.Value = '10';
            app.LinearmotionEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.LinearmotionEditFieldLabel.Text = 'Linear motion?';
            app.LinearmotionEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.LinearmotionEditField.ValueChangedFcn = createCallbackFcn(app, @LinearMotion, true);
            app.LinearmotionEditField.Value = '1';
            app.BrownstdmultiplerEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.BrownstdmultiplerEditFieldLabel.Text = 'Brown std multipler';
            app.BrownstdmultiplerEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.BrownstdmultiplerEditField.ValueChangedFcn = createCallbackFcn(app, @BrownMult, true);
            app.BrownstdmultiplerEditField.Value = '3';
            app.UselocaldensityEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.UselocaldensityEditFieldLabel.Text = 'Use local density?';
            app.UselocaldensityEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.UselocaldensityEditField.ValueChangedFcn = createCallbackFcn(app, @LocalDensity, true);
            app.UselocaldensityEditField.Value = '1';
            app.MaxsearchradiusnmEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MaxsearchradiusnmEditFieldLabel.Text = 'Max search radius (nm)';
            app.MaxsearchradiusnmEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MaxsearchradiusnmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRadius, true);
            app.MaxsearchradiusnmEditField.Value = '400';
            app.MaxsearchangleEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.MaxsearchangleEditFieldLabel.Text = 'Max search angle';
            app.MaxsearchangleEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.MaxsearchangleEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAngle, true);
            app.MaxsearchangleEditField.Value = '360';
            app.SaveparametersButton = uibutton(app.uTrackparametersTab, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveuTrackparams, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.LoadparametersButton = uibutton(app.uTrackparametersTab, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loaduTrackparams, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.ResettodefaultsButton = uibutton(app.uTrackparametersTab, 'push');
            app.ResettodefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultReset, true);
            app.ResettodefaultsButton.Text = 'Reset to defaults';
            app.PixelInfo = uibutton(app.uTrackparametersTab, 'push');
            app.PixelInfo.ButtonPushedFcn = createCallbackFcn(app, @PxInfo, true);
            app.PixelInfo.Text = '?';
            app.GapInfo = uibutton(app.uTrackparametersTab, 'push');
            app.GapInfo.ButtonPushedFcn = createCallbackFcn(app, @Gapinfo, true);
            app.GapInfo.Text = '?';
            app.MinLengthInfo = uibutton(app.uTrackparametersTab, 'push');
            app.MinLengthInfo.ButtonPushedFcn = createCallbackFcn(app, @minLengthInfo, true);
            app.MinLengthInfo.Text = '?';
            app.MinRadiusInfo = uibutton(app.uTrackparametersTab, 'push');
            app.MinRadiusInfo.ButtonPushedFcn = createCallbackFcn(app, @minRadiusInfo, true);
            app.MinRadiusInfo.Text = '?';
            app.MaxRadiusInfo = uibutton(app.uTrackparametersTab, 'push');
            app.MaxRadiusInfo.ButtonPushedFcn = createCallbackFcn(app, @maxRadiusInfo, true);
            app.MaxRadiusInfo.Text = '?';
            app.MaxAngleInfo = uibutton(app.uTrackparametersTab, 'push');
            app.MaxAngleInfo.ButtonPushedFcn = createCallbackFcn(app, @maxAngleInfo, true);
            app.MaxAngleInfo.Text = '?';
            app.MergeSplitInfo = uibutton(app.uTrackparametersTab, 'push');
            app.MergeSplitInfo.ButtonPushedFcn = createCallbackFcn(app, @mergeSplitInfo, true);
            app.MergeSplitInfo.Text = '?';
            app.LinearMotionInfo = uibutton(app.uTrackparametersTab, 'push');
            app.LinearMotionInfo.ButtonPushedFcn = createCallbackFcn(app, @linearMotionInfo, true);
            app.LinearMotionInfo.Text = '?';
            app.BrownInfo = uibutton(app.uTrackparametersTab, 'push');
            app.BrownInfo.ButtonPushedFcn = createCallbackFcn(app, @brownInfo, true);
            app.BrownInfo.Text = '?';
            app.LocalDensityInfo = uibutton(app.uTrackparametersTab, 'push');
            app.LocalDensityInfo.ButtonPushedFcn = createCallbackFcn(app, @localDensityInfo, true);
            app.LocalDensityInfo.Text = '?';
            app.BasicparametersLabel = uilabel(app.uTrackparametersTab);
            app.BasicparametersLabel.Text = 'Basic parameters';
            app.ImportantparametersLabel = uilabel(app.uTrackparametersTab);
            app.ImportantparametersLabel.Text = 'Important parameters';
            app.OptionalparametersLabel = uilabel(app.uTrackparametersTab);
            app.OptionalparametersLabel.Text = 'Optional parameters';
            app.DontchangeunlessyouknowwhatyouaredoingLabel = uilabel(app.uTrackparametersTab);
            app.DontchangeunlessyouknowwhatyouaredoingLabel.Text = 'Don''t change unless you know what you are doing! ';
            app.GappenaltyEditFieldLabel = uilabel(app.uTrackparametersTab);
            app.GappenaltyEditFieldLabel.Text = 'Gap penalty';
            app.GappenaltyEditField = uieditfield(app.uTrackparametersTab, 'text');
            app.GappenaltyEditField.ValueChangedFcn = createCallbackFcn(app, @GapPenalty, true);
            app.GappenaltyEditField.Value = '1';
            app.GapPenaltyInfo = uibutton(app.uTrackparametersTab, 'push');
            app.GapPenaltyInfo.ButtonPushedFcn = createCallbackFcn(app, @gapPenaltyInfo, true);
            app.GapPenaltyInfo.Text = '?';
            app.uTrackJaqamanetal2008NatureMethodsLabel = uilabel(app.uTrackparametersTab);
            app.uTrackJaqamanetal2008NatureMethodsLabel.Text = 'uTrack: Jaqaman et al., 2008 (Nature Methods)';
            app.NOBIASparametersTab = uitab(app.TabGroup);
            app.NOBIASparametersTab.Title = 'NOBIAS parameters';
            app.KappaEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.KappaEditFieldLabel.Text = 'Kappa';
            app.KappaEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.KappaEditField.ValueChangedFcn = createCallbackFcn(app, @Nkappa, true);
            app.KappaEditField.Value = 10;
            app.MotionblurEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.MotionblurEditFieldLabel.Text = 'Motion blur?';
            app.MotionblurEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.MotionblurEditField.ValueChangedFcn = createCallbackFcn(app, @NmotionBlur, true);
            app.MotionblurEditField.Value = 1;
            app.FrametimesecEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.FrametimesecEditFieldLabel.Text = 'Frame time (sec)';
            app.FrametimesecEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.FrametimesecEditField.ValueChangedFcn = createCallbackFcn(app, @Nframetime, true);
            app.FrametimesecEditField.Value = 0.1;
            app.PixelsizeumEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size (um)';
            app.PixelsizeumEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.PixelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @NpxSize, true);
            app.PixelsizeumEditField.Value = 0.099;
            app.Button_12 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @NframeInfo, true);
            app.Button_12.Text = '?';
            app.Button_13 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @NpxSizeInfo, true);
            app.Button_13.Text = '?';
            app.Button_14 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @NmotionBlurInfo, true);
            app.Button_14.Text = '?';
            app.Button_15 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @NkappaInfo, true);
            app.Button_15.Text = '?';
            app.LoadparametersButton_NOBIAS = uibutton(app.NOBIASparametersTab, 'push');
            app.LoadparametersButton_NOBIAS.ButtonPushedFcn = createCallbackFcn(app, @Nloadparams, true);
            app.LoadparametersButton_NOBIAS.Text = 'Load parameters';
            app.Saveparameters_NOBIAS = uibutton(app.NOBIASparametersTab, 'push');
            app.Saveparameters_NOBIAS.ButtonPushedFcn = createCallbackFcn(app, @Nsaveparams, true);
            app.Saveparameters_NOBIAS.Text = 'Save parameters';
            app.ResettodefaultsButton_2 = uibutton(app.NOBIASparametersTab, 'push');
            app.ResettodefaultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @Ndefaults, true);
            app.ResettodefaultsButton_2.Text = 'Reset to defaults';
            app.BasicparametersLabel_2 = uilabel(app.NOBIASparametersTab);
            app.BasicparametersLabel_2.Text = 'Basic parameters';
            app.AdvancedparametersLabel = uilabel(app.NOBIASparametersTab);
            app.AdvancedparametersLabel.Text = 'Advanced parameters';
            app.DontchangeunlessyouknowwhatyouaredoingLabel_2 = uilabel(app.NOBIASparametersTab);
            app.DontchangeunlessyouknowwhatyouaredoingLabel_2.Text = 'Don''t change unless you know what you are doing! ';
            app.GammaEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @Ngamma, true);
            app.GammaEditField.Value = 0.1;
            app.AlphaEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @Nalpha, true);
            app.AlphaEditField.Value = 1;
            app.BurnEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.BurnEditFieldLabel.Text = 'Burn';
            app.BurnEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.BurnEditField.ValueChangedFcn = createCallbackFcn(app, @Nburn, true);
            app.BurnEditField.Value = 200;
            app.DisplayparametersLabel = uilabel(app.NOBIASparametersTab);
            app.DisplayparametersLabel.Text = 'Display parameters';
            app.IterationsEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @Niter, true);
            app.IterationsEditField.Value = 20000;
            app.DisplayfrequencyEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.DisplayfrequencyEditFieldLabel.Text = 'Display frequency';
            app.DisplayfrequencyEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.DisplayfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @Nfreq, true);
            app.DisplayfrequencyEditField.Value = 1000;
            app.PlotEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.PlotEditFieldLabel.Text = 'Plot?';
            app.PlotEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.PlotEditField.ValueChangedFcn = createCallbackFcn(app, @Nplot, true);
            app.PlotEditField.Value = 1000;
            app.AsymmetricEditFieldLabel = uilabel(app.NOBIASparametersTab);
            app.AsymmetricEditFieldLabel.Text = 'Asymmetric?';
            app.AsymmetricEditField = uieditfield(app.NOBIASparametersTab, 'numeric');
            app.AsymmetricEditField.ValueChangedFcn = createCallbackFcn(app, @Nasym, true);
            app.Button_18 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @NasymInfo, true);
            app.Button_18.Text = '?';
            app.Button_19 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @NiterInfo, true);
            app.Button_19.Text = '?';
            app.Button_20 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @NfreqInfo, true);
            app.Button_20.Text = '?';
            app.Button_21 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @NplotInfo, true);
            app.Button_21.Text = '?';
            app.Button_22 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @NgammaInfo, true);
            app.Button_22.Text = '?';
            app.Button_23 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @NalphaInfo, true);
            app.Button_23.Text = '?';
            app.Button_24 = uibutton(app.NOBIASparametersTab, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @NburnInfo, true);
            app.Button_24.Text = '?';
            app.NOBIASChenetal2021FrontiersinBioinformaticsLabel = uilabel(app.NOBIASparametersTab);
            app.NOBIASChenetal2021FrontiersinBioinformaticsLabel.Text = 'NOBIAS: Chen et al., 2021 (Frontiers in Bioinformatics) ';
            app.TrackingfilteringTab = uitab(app.TabGroup);
            app.TrackingfilteringTab.Title = 'Tracking & filtering';
            app.Step3CreateMasksLabel = uilabel(app.TrackingfilteringTab);
            app.Step3CreateMasksLabel.Text = 'Step 3: Create Masks';
            app.Step5RunTrackingLabel = uilabel(app.TrackingfilteringTab);
            app.Step5RunTrackingLabel.Text = 'Step 5: Run Tracking';
            app.RunuTrackButton = uibutton(app.TrackingfilteringTab, 'push');
            app.RunuTrackButton.ButtonPushedFcn = createCallbackFcn(app, @uTrack, true);
            app.RunuTrackButton.Text = 'Run uTrack';
            app.Step6FilterSCTracksLabel = uilabel(app.TrackingfilteringTab);
            app.Step6FilterSCTracksLabel.Text = 'Step 6: Filter SC Tracks';
            app.FiltertracksButton = uibutton(app.TrackingfilteringTab, 'push');
            app.FiltertracksButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTracks, true);
            app.FiltertracksButton.Text = 'Filter tracks!';
            app.Step4OrganizeMasksLabel = uilabel(app.TrackingfilteringTab);
            app.Step4OrganizeMasksLabel.Text = 'Step 4: Organize Masks';
            app.OrganizemasksButton = uibutton(app.TrackingfilteringTab, 'push');
            app.OrganizemasksButton.ButtonPushedFcn = createCallbackFcn(app, @MaskOrg, true);
            app.OrganizemasksButton.Text = 'Organize masks';
            app.Button_4 = uibutton(app.TrackingfilteringTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @MaskOrgInfo, true);
            app.Button_4.Text = '?';
            app.Button_6 = uibutton(app.TrackingfilteringTab, 'push');
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.TrackingfilteringTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @uTrackInfo, true);
            app.Button_7.Text = '?';
            app.Step7MSDAnalysisLabel_3 = uilabel(app.TrackingfilteringTab);
            app.Step7MSDAnalysisLabel_3.Text = 'Step 7: MSD Analysis';
            app.RunMSDAnalysisButton_3 = uibutton(app.TrackingfilteringTab, 'push');
            app.RunMSDAnalysisButton_3.Text = 'Run MSD Analysis';
            app.Button_16 = uibutton(app.TrackingfilteringTab, 'push');
            app.Button_16.Text = '?';
            app.RunNOBIASButton_3 = uibutton(app.TrackingfilteringTab, 'push');
            app.RunNOBIASButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunNOBIAS, true);
            app.RunNOBIASButton_3.Text = 'Run NOBIAS';
            app.Button_17 = uibutton(app.TrackingfilteringTab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @FIlterTracksInfo, true);
            app.Button_17.Text = '?';
            app.Step8NOBIASAnalysisLabel_3 = uilabel(app.TrackingfilteringTab);
            app.Step8NOBIASAnalysisLabel_3.Text = 'Step 8: NOBIAS Analysis';
            app.MinlocsEditFieldLabel = uilabel(app.TrackingfilteringTab);
            app.MinlocsEditFieldLabel.Text = 'Min locs';
            app.MinlocsEditField = uieditfield(app.TrackingfilteringTab, 'numeric');
            app.MinlocsEditField.ValueChangedFcn = createCallbackFcn(app, @minLocs, true);
            app.MinlocsEditField.Value = 10;
            app.MaxblinkEditFieldLabel = uilabel(app.TrackingfilteringTab);
            app.MaxblinkEditFieldLabel.Text = 'Max blink';
            app.MaxblinkEditField = uieditfield(app.TrackingfilteringTab, 'numeric');
            app.MaxblinkEditField.ValueChangedFcn = createCallbackFcn(app, @maxBlink, true);
            app.MaxblinkEditField.Value = 0.5;
            app.nmpxEditFieldLabel = uilabel(app.TrackingfilteringTab);
            app.nmpxEditFieldLabel.Text = 'nm/px';
            app.nmpxEditField = uieditfield(app.TrackingfilteringTab, 'numeric');
            app.nmpxEditField.ValueChangedFcn = createCallbackFcn(app, @nm2px, true);
            app.nmpxEditField.Value = 99;
            app.WigglepxoptionalEditFieldLabel = uilabel(app.TrackingfilteringTab);
            app.WigglepxoptionalEditFieldLabel.Text = 'Wiggle (px, optional)';
            app.WigglepxoptionalEditField = uieditfield(app.TrackingfilteringTab, 'numeric');
            app.WigglepxoptionalEditField.ValueChangedFcn = createCallbackFcn(app, @wiggle, true);
            app.RunCellACDCseeguideformoreinfoLabel = uilabel(app.TrackingfilteringTab);
            app.RunCellACDCseeguideformoreinfoLabel.Text = 'Run Cell-ACDC -- see guide for more info';
            app.BitspxoptionalEditFieldLabel = uilabel(app.TrackingfilteringTab);
            app.BitspxoptionalEditFieldLabel.Text = 'Bits (px, optional)';
            app.BitspxoptionalEditField = uieditfield(app.TrackingfilteringTab, 'numeric');
            app.BitspxoptionalEditField.ValueChangedFcn = createCallbackFcn(app, @small, true);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPTbot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end