classdef uTrack_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadparametersButton           matlab.ui.control.Button
        SaveparametersButton           matlab.ui.control.Button
        TRACKButton                    matlab.ui.control.Button
        MaxangleEditField              matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel         matlab.ui.control.Label
        MaxsearchradiusnmEditField     matlab.ui.control.NumericEditField
        MaxsearchradiusnmEditFieldLabel  matlab.ui.control.Label
        MinsearchradiusnmEditField     matlab.ui.control.NumericEditField
        MinsearchradiusnmEditFieldLabel  matlab.ui.control.Label
        MintracklengthframesEditField  matlab.ui.control.NumericEditField
        MintracklengthframesEditFieldLabel  matlab.ui.control.Label
        TimegapframesEditFieldLabel    matlab.ui.control.Label
        EnterinparametersorselectsavedoptionsfromfileLabel  matlab.ui.control.Label
        TimegapframesEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        SPTbot % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           %  app.SPTbot = SPTbot;
        end

        % Button pushed function: LoadparametersButton
        function loadParameters(app, event)
            load("trackingParameters.mat"); 
            app.TimegapframesEditField.Value = trackingParameters.gapWindow;
        end

        % Value changed function: TimegapframesEditField
        function gap(app, event)
            gapWindow = app.TimegapframesEditField.Value;
            
        end

        % Value changed function: MaxsearchradiusnmEditField
        function MaxRadius(app, event)
            maxRadius = app.MaxsearchradiusnmEditField.Value;
            
        end

        % Value changed function: MintracklengthframesEditField
        function minLength(app, event)
            minLength = app.MintracklengthframesEditField.Value;
            
        end

        % Button pushed function: SaveparametersButton
        function SaveParameters(app, event)

            trackingParameters.gapWindow = app.TimegapframesEditField.Value;
            trackingParameters.maxRadius = app.MaxsearchradiusnmEditField.Value;
            trackingParameters.minLength = app.MintracklengthframesEditField.Value;
            uisave(trackingParameters)

        end

        % Button pushed function: TRACKButton
        function track(app, event)
            gapWindow = app.TimegapframesEditField.Value;
            maxRadius = app.MaxsearchradiusnmEditField.Value;
            minLength = app.MintracklengthframesEditField.Value;
            Tracking(gapWindow, maxRadius, minLength)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimegapframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimegapframesEditField.ValueChangedFcn = createCallbackFcn(app, @gap, true);
            app.TimegapframesEditField.Value = 15;
            app.EnterinparametersorselectsavedoptionsfromfileLabel = uilabel(app.UIFigure);
            app.EnterinparametersorselectsavedoptionsfromfileLabel.Text = 'Enter in parameters (or select saved options from file)';
            app.TimegapframesEditFieldLabel = uilabel(app.UIFigure);
            app.TimegapframesEditFieldLabel.Text = 'Time gap (frames)';
            app.MintracklengthframesEditFieldLabel = uilabel(app.UIFigure);
            app.MintracklengthframesEditFieldLabel.Text = 'Min track length (frames)';
            app.MintracklengthframesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MintracklengthframesEditField.ValueChangedFcn = createCallbackFcn(app, @minLength, true);
            app.MinsearchradiusnmEditFieldLabel = uilabel(app.UIFigure);
            app.MinsearchradiusnmEditFieldLabel.Text = 'Min search radius (nm)';
            app.MinsearchradiusnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxsearchradiusnmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxsearchradiusnmEditFieldLabel.Text = 'Max search radius (nm)';
            app.MaxsearchradiusnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxsearchradiusnmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRadius, true);
            app.MaxangleEditFieldLabel = uilabel(app.UIFigure);
            app.MaxangleEditFieldLabel.Text = 'Max angle';
            app.MaxangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TRACKButton = uibutton(app.UIFigure, 'push');
            app.TRACKButton.ButtonPushedFcn = createCallbackFcn(app, @track, true);
            app.TRACKButton.Text = 'TRACK';
            app.SaveparametersButton = uibutton(app.UIFigure, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParameters, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.LoadparametersButton = uibutton(app.UIFigure, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadParameters, true);
            app.LoadparametersButton.Text = 'Load parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uTrack_settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end